classdef AES_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        InspectorTab                matlab.ui.container.Tab
        EnterButton_2               matlab.ui.control.StateButton
        CipherKeyEditField          matlab.ui.control.EditField
        CipherKeyEditFieldLabel     matlab.ui.control.Label
        EditField_2                 matlab.ui.control.EditField
        OutputTextArea_2            matlab.ui.control.TextArea
        OutputTextArea_2Label       matlab.ui.control.Label
        YourKeyTextArea             matlab.ui.control.TextArea
        YourKeyTextAreaLabel        matlab.ui.control.Label
        InputStateTextArea          matlab.ui.control.TextArea
        InputStateTextAreaLabel     matlab.ui.control.Label
        EnterButton                 matlab.ui.control.StateButton
        EditField                   matlab.ui.control.EditField
        InputMessageEditField       matlab.ui.control.EditField
        InputMessageEditFieldLabel  matlab.ui.control.Label
        TextArea                    matlab.ui.control.TextArea
        InstructionsTab             matlab.ui.container.Tab
        AESInspectorPanel           matlab.ui.container.Panel
        Image                       matlab.ui.control.Image
        OutputTextArea              matlab.ui.control.TextArea
        OutputTextAreaLabel         matlab.ui.control.Label
        ButtonGroup                 matlab.ui.container.ButtonGroup
        XDButton                    matlab.ui.control.RadioButton
        DecryptionButton            matlab.ui.control.RadioButton
        EncryptionButton            matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        InputMessage; % property to store the value from the input message 
        ConvertedInput; %property to store converted input
        InputChecker; %property used to collect information about validation
        %of the input
        ConvertedDisplay; %property to store converted values to display
        CipherKey; %property to store a cipher key
        InputCheckTest; %property to store the value under test 
        ConvertedCipherKey; %property to store converted 4x4 matrix of cipher
    end
    
    methods (Access = private)
        
        function InputCheck(app)
            %function designed to check wheter the valid characters are
            %inputted
            % Define valid characters (0-9, a-f, A-F)
            validCharacters = ['0':'9' 'a':'f' 'A':'F'];
            
            %check if the pressed key is not valid member 
            if ~ismember(app.InputCheckTest,validCharacters);
              InvalidInputWarning(app);
              
            else 
                app.InputChecker = 1;
            end
        end
    
        function InvalidInputWarning(app)
            %warning window for invalid input
        opts = struct('WindowStyle','modal',... 
              'Interpreter','tex');
        f = warndlg('\color{blue}Some of the characters are not in the hexadecimal. Please enter a new value.',...
            'Invalid Input', opts);
        end

        function InvalidLenghtWarning(app)
            %warning window for invalid lenght of the message 
            opts = struct('WindowStyle','modal',... 
              'Interpreter','tex');
            f = warndlg('\color{blue}The inputted value does not contain 16 bytes. Please enter a new value.',...
            'Invalid Input', opts);
        end

        function ConversionForTextArea(app)
            %necessary conversions needed for display 
            app.ConvertedDisplay = num2str(app.ConvertedDisplay);
            app.ConvertedDisplay =cellstr(app.ConvertedDisplay);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: InputMessageEditField
        function InputMessageEditFieldValueChanged(app, event)
            %read and store the input message for the furter use
            app.InputMessage = app.InputMessageEditField.Value;
                    
        end

        % Value changed function: EnterButton
        function EnterButtonValueChanged(app, event)
            %value = app.EnterButton.Value;
            %test if the entered value is in hex
            app.InputCheckTest = app.InputMessage;
            InputCheck(app);
            
            if app.InputChecker == 1
                %if input is valid get size ot the input message
                if length(app.InputMessage) == 32
                   %Convert the input to a state 4x4 Matrix 
                  app.ConvertedInput = conversion(app.InputMessage,0);
                  app.ConvertedDisplay = app.ConvertedInput;
                  ConversionForTextArea(app);
                  app.InputStateTextArea.Value = app.ConvertedDisplay;
                  
                else
                  InvalidLenghtWarning(app);  
                end
               
            end
   
        end

        % Value changed function: CipherKeyEditField
        function CipherKeyEditFieldValueChanged(app, event)
            %store the cipher key 
            app.CipherKey = app.CipherKeyEditField.Value;
            
        end

        % Value changed function: EnterButton_2
        function EnterButton_2ValueChanged(app, event)
            %value = app.EnterButton_2.Value;
            %check if the entered value is in hex
            app.InputCheckTest = app.CipherKey;
            InputCheck(app);

            if app.InputChecker == 1
                %if input is valid get size ot the input key
                if length(app.CipherKey) == 32
                   %Convert the input to a state 4x4 Matrix 
                  app.ConvertedCipherKey = conversion(app.CipherKey,0);
                  app.ConvertedDisplay = app.ConvertedCipherKey;
                  ConversionForTextArea(app);
                  app.YourKeyTextArea.Value = app.ConvertedDisplay;
                  
                else
                  InvalidLenghtWarning(app);  
                end
               
            end

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = 'Button Group';
            app.EncryptionButton = uiradiobutton(app.ButtonGroup);
            app.EncryptionButton.Text = 'Encryption';
            app.EncryptionButton.Value = true;
            app.DecryptionButton = uiradiobutton(app.ButtonGroup);
            app.DecryptionButton.Text = 'Decryption';
            app.XDButton = uiradiobutton(app.ButtonGroup);
            app.XDButton.Text = 'XD ';
            app.OutputTextAreaLabel = uilabel(app.UIFigure);
            app.OutputTextAreaLabel.Text = 'Output';
            app.OutputTextArea = uitextarea(app.UIFigure);
            app.AESInspectorPanel = uipanel(app.UIFigure);
            app.AESInspectorPanel.Title = 'AES Inspector';
            app.Image = uiimage(app.AESInspectorPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Pics', 'UOSLogo_Primary_Violet_RGB.png');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InspectorTab = uitab(app.TabGroup);
            app.InspectorTab.Title = 'Inspector';
            app.TextArea = uitextarea(app.InspectorTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'AES Inspector is a tool utilised for inspecting and analyzing Advanced Encryption Standard (AES) encryption. This app was designed to assist users in comprehending and addressing issues regarding encrypted data. It was developed by students from the University of Sheffield: Kacper Sikorski, Charlie Baldwin, and Boon Kean Teo.'; ''; 'The app should be self-explanatory, but please refer to the instructions tab if in doubt. '};
            app.InputMessageEditFieldLabel = uilabel(app.InspectorTab);
            app.InputMessageEditFieldLabel.Text = 'Input Message';
            app.InputMessageEditField = uieditfield(app.InspectorTab, 'text');
            app.InputMessageEditField.CharacterLimits = [0 32];
            app.InputMessageEditField.ValueChangedFcn = createCallbackFcn(app, @InputMessageEditFieldValueChanged, true);
            app.EditField = uieditfield(app.InspectorTab, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Value = 'Please type 16 byte hex string with your input message below and press enter.';
            app.EnterButton = uibutton(app.InspectorTab, 'state');
            app.EnterButton.ValueChangedFcn = createCallbackFcn(app, @EnterButtonValueChanged, true);
            app.EnterButton.Text = 'Enter';
            app.InputStateTextAreaLabel = uilabel(app.InspectorTab);
            app.InputStateTextAreaLabel.Text = 'Input State';
            app.InputStateTextArea = uitextarea(app.InspectorTab);
            app.InputStateTextArea.Editable = 'off';
            app.YourKeyTextAreaLabel = uilabel(app.InspectorTab);
            app.YourKeyTextAreaLabel.Text = 'Your Key';
            app.YourKeyTextArea = uitextarea(app.InspectorTab);
            app.YourKeyTextArea.Editable = 'off';
            app.OutputTextArea_2Label = uilabel(app.InspectorTab);
            app.OutputTextArea_2Label.Text = 'Output';
            app.OutputTextArea_2 = uitextarea(app.InspectorTab);
            app.OutputTextArea_2.Editable = 'off';
            app.EditField_2 = uieditfield(app.InspectorTab, 'text');
            app.EditField_2.Editable = 'off';
            app.EditField_2.Value = 'Please type 16 byte hex string with your cipher key below and press enter.';
            app.CipherKeyEditFieldLabel = uilabel(app.InspectorTab);
            app.CipherKeyEditFieldLabel.Text = 'Cipher Key';
            app.CipherKeyEditField = uieditfield(app.InspectorTab, 'text');
            app.CipherKeyEditField.CharacterLimits = [0 32];
            app.CipherKeyEditField.ValueChangedFcn = createCallbackFcn(app, @CipherKeyEditFieldValueChanged, true);
            app.EnterButton_2 = uibutton(app.InspectorTab, 'state');
            app.EnterButton_2.ValueChangedFcn = createCallbackFcn(app, @EnterButton_2ValueChanged, true);
            app.EnterButton_2.Text = 'Enter';
            app.InstructionsTab = uitab(app.TabGroup);
            app.InstructionsTab.Title = 'Instructions';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AES_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end